classdef my_first_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        loadButton    matlab.ui.control.Button
        exportButton  matlab.ui.control.Button
        Slider        matlab.ui.control.Slider
        SliderLabel   matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Year % Description
        Data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            S=webread('http://climatedataapi.worldbank.org/climateweb/rest/v1/country/cru/tas/year/USA');
            app.Year=[S.year];
            app.Data=[S.data];
            plot(app.UIAxes, [S.year], [S.data])
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            smoothedData=smooth(app.Data, changingValue);
            plot(app.UIAxes, app.Year, app.Data)
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.Year, smoothedData)
            hold(app.UIAxes, 'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.exportButton = uibutton(app.UIFigure, 'push');
            app.exportButton.Text = 'export ';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_first_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end